classdef camera_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GetStateButton       matlab.ui.control.Button
        StartMovieOnButtonPressDropDown  matlab.ui.control.DropDown
        StartMovieOnButtonPressDropDownLabel  matlab.ui.control.Label
        EndMovieButton       matlab.ui.control.Button
        StartMovieButton     matlab.ui.control.Button
        StopLiveViewButton   matlab.ui.control.Button
        StartLiveViewButton  matlab.ui.control.Button
        TakePhotoButton      matlab.ui.control.Button
        TerminateButton      matlab.ui.control.Button
        InitializeButton     matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainNcaApp % the main NCA GUI app
        cameraState; %struct to store camera state booleans
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainNcaApp)
            % Store main app instance in camera app
            app.mainNcaApp = mainNcaApp;
            
            % Populate camera state booleans
            app.cameraState = edsdk_mex('getState');
        end

        % Close request function: UIFigure
        function cameraAppCloseRequest(app, event)
            if isvalid(app.mainNcaApp)
                app.mainNcaApp.cameraButton.Enable = "on";
            end

            % Delete the camera app
            delete(app)
            
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)

            % Disable button from repeated presses
            app.InitializeButton.Enable = "off";

            % initialize EDSDK
            edsdk_mex('init');

            % Enable the other buttons
            app.TerminateButton.Enable = "on";
            app.TakePhotoButton.Enable = "on";
            app.StartLiveViewButton.Enable = "on";
            app.StopLiveViewButton.Enable = "on";
        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            
            % Disable button to prevent repeated presses
            app.TerminateButton.Enable = "off";

            % Disable all other buttons except get state, enable initialize
            app.InitializeButton.Enable = "on";
            app.TakePhotoButton.Enable = "off";
            app.StartLiveViewButton.Enable  = "off";
            app.StopLiveViewButton.Enable = "off";
            
            app.StartMovieButton.Enable = "off";
            app.StopLiveViewButton.Enable = "off";
            app.StartMovieOnButtonPressDropDown.Enable = 'off';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @cameraAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Tooltip = {'Initializes the Canon API and creates a session with the camera.'};
            app.InitializeButton.Text = 'Initialize';
            app.TerminateButton = uibutton(app.UIFigure, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Enable = 'off';
            app.TerminateButton.Text = 'Terminate';
            app.TakePhotoButton = uibutton(app.UIFigure, 'push');
            app.TakePhotoButton.Enable = 'off';
            app.TakePhotoButton.Text = 'Take Photo';
            app.StartLiveViewButton = uibutton(app.UIFigure, 'push');
            app.StartLiveViewButton.Enable = 'off';
            app.StartLiveViewButton.Text = 'Start Live View';
            app.StopLiveViewButton = uibutton(app.UIFigure, 'push');
            app.StopLiveViewButton.Enable = 'off';
            app.StopLiveViewButton.Text = 'Stop Live View';
            app.StartMovieButton = uibutton(app.UIFigure, 'push');
            app.StartMovieButton.Enable = 'off';
            app.StartMovieButton.Text = 'Start Movie';
            app.EndMovieButton = uibutton(app.UIFigure, 'push');
            app.EndMovieButton.Enable = 'off';
            app.EndMovieButton.Text = 'End Movie';
            app.StartMovieOnButtonPressDropDownLabel = uilabel(app.UIFigure);
            app.StartMovieOnButtonPressDropDownLabel.Enable = 'off';
            app.StartMovieOnButtonPressDropDownLabel.Text = 'Start Movie On Button Press';
            app.StartMovieOnButtonPressDropDown = uidropdown(app.UIFigure);
            app.StartMovieOnButtonPressDropDown.Items = {'Start Movie', 'Start (Main app)', 'Rampdown 1 (Main app)', 'Rampdown 2 (Main app)', 'Rampdown O2 (Main app)'};
            app.StartMovieOnButtonPressDropDown.Enable = 'off';
            app.StartMovieOnButtonPressDropDown.Tooltip = {'The selected button will also start the movie recording.'};
            app.StartMovieOnButtonPressDropDown.Value = 'Start Movie';
            app.GetStateButton = uibutton(app.UIFigure, 'push');
            app.GetStateButton.Text = 'Get State';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camera_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end